classdef Appyen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LabelGauge              matlab.ui.control.Label
        Gauge                   matlab.ui.control.Gauge
        LabelSlider             matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        Button                  matlab.ui.control.Button
        LabelEditField          matlab.ui.control.Label
        EditField               matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        Lamp2                   matlab.ui.control.Lamp
        Label2                  matlab.ui.control.Label
        EditField2              matlab.ui.control.EditField
        Lamp3                   matlab.ui.control.Lamp
        Label3                  matlab.ui.control.Label
        EditField3              matlab.ui.control.EditField
        Lamp4                   matlab.ui.control.Lamp
        Label4                  matlab.ui.control.Label
        EditField4              matlab.ui.control.EditField
        Lamp5                   matlab.ui.control.Lamp
        Label5                  matlab.ui.control.Label
        EditField5              matlab.ui.control.EditField
        Lamp6                   matlab.ui.control.Lamp
        Label6                  matlab.ui.control.Label
        EditField6              matlab.ui.control.EditField
        LabelSemicircularGauge  matlab.ui.control.Label
        SemicircularGauge       matlab.ui.control.SemicircularGauge
        TextArea2               matlab.ui.control.TextArea
        Button2                 matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        YkseklikEditFieldLabel  matlab.ui.control.Label
        YkseklikEditField       matlab.ui.control.EditField
        up                      matlab.ui.control.Lamp
        down                    matlab.ui.control.Lamp
        left                    matlab.ui.control.Lamp
        right                   matlab.ui.control.Lamp
        JOSTICKLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button
        function ButtonButtonPushed(app, event)
        global socket
        fprintf(socket,'%d',-1);
         
         
        end

        % Button pushed function: Button2
        function Button2ButtonPushed(app, event)
            function calljoystick(axes,buttons,povs)
            leftRight = axes(1:1);           %
            upDown = axes(2:2);
            if(upDown == 1)
                disp(upDown)
                app.down.Color = 'green';
                drawnow();
             else
                app.down.Color = 'white';
                drawnow();
            
            end
            if(upDown == -1)
                app.up.Color = 'green';
                drawnow();
             else
                app.up.Color = 'white';
                drawnow();
            
            end
            if(leftRight == -1)
                app.left.Color = 'green';
                drawnow();
             else
                app.left.Color = 'white';
                drawnow();
            
            end
            if(leftRight == 1)
                app.right.Color = 'green';
                drawnow();
             else
                app.right.Color = 'white';
                drawnow();
            
            end 
            end
        joy = vrjoystick(1);    %joystick objesi oluşturuyor
         
        app.Button.Enable = true;
          drawnow();
            import matlab.net.base64decode
            d = 1;
            g = 9.81;
          
            global socket
            socket= tcpip('localhost',3001,'NetworkRole', 'server');
            socket.InputBufferSize = 100000;
             disp('socket olusturuldu')
             disp('socket aciliyor')
             fopen(socket);
             disp('socket acildi')
             tempString = "";
             i = 0;
             while(true)
                data = fscanf(socket); % komplesini oku (52) veriyi okudu
                
           [axes,buttons,povs] = read(joy);   %okuduğum değerler    
           calljoystick(axes,buttons,povs);


                if(data)
                    tempString = tempString + data;
                end
                 if contains(data,"}")
                    % disp(tempString)
                    kullanilacakData = jsondecode(tempString); % datalari obje kullanabil
                    motorlar = kullanilacakData.motorlar;
                    app.EditField.Value = num2str(motorlar(1:1));
                    app.EditField2.Value = num2str(motorlar(2:2));
                    app.EditField3.Value = num2str(motorlar(3:3));
                    app.EditField4.Value = num2str(motorlar(4:4));
                    app.EditField5.Value = num2str(motorlar(5:5));
                    app.EditField6.Value = num2str(motorlar(6:6));     
                    sicaklik = kullanilacakData.sicaklik;
                    basinc = kullanilacakData.basinc;
                    app.YkseklikEditField.Value = int2str((basinc)/(d*g));
                    resim = kullanilacakData.resim;
                    app.Gauge.Value = sicaklik ;
                    app.SemicircularGauge.Value = basinc;
                    decoded = base64decode(resim);
                    fileId = fopen("C:\Users\RidOne\Desktop\matlabArayuz\"+i+".jpg","w");
                    fwrite(fileId,decoded);
                    fclose(fileId);
                    app.Image.ImageSource="C:\Users\RidOne\Desktop\matlabArayuz\"+i+".jpg";
                    drawnow();
                    tempString = "";
                    
                    i = rem(i+1, 250);
                 end
          
             fprintf(socket,'%f',axes);
             end
                
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LabelGauge = uilabel(app.UIFigure);
            app.LabelGauge.Text = 'Sıcaklık';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110];
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Pil doluluk';
            app.Slider = uislider(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Text = 'Acil Durdurma Butonu';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'motor 1';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'çekilen akım değerleri';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'motor 1';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.Lamp3 = uilamp(app.UIFigure);
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'motor 1';
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.Lamp4 = uilamp(app.UIFigure);
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'motor 1';
            app.EditField4 = uieditfield(app.UIFigure, 'text');
            app.Lamp5 = uilamp(app.UIFigure);
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'motor 1';
            app.EditField5 = uieditfield(app.UIFigure, 'text');
            app.Lamp6 = uilamp(app.UIFigure);
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'motor 1';
            app.EditField6 = uieditfield(app.UIFigure, 'text');
            app.LabelSemicircularGauge = uilabel(app.UIFigure);
            app.LabelSemicircularGauge.Text = 'Basınç göstergesi';
            app.SemicircularGauge = uigauge(app.UIFigure, 'semicircular');
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Value = {''; ''; 'Araç yönelimi'};
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed, true);
            app.Button2.Text = 'port aç';
            app.Image = uiimage(app.UIFigure);
            app.YkseklikEditFieldLabel = uilabel(app.UIFigure);
            app.YkseklikEditFieldLabel.Text = 'Yükseklik';
            app.YkseklikEditField = uieditfield(app.UIFigure, 'text');
            app.up = uilamp(app.UIFigure);
            app.down = uilamp(app.UIFigure);
            app.left = uilamp(app.UIFigure);
            app.right = uilamp(app.UIFigure);
            app.JOSTICKLabel = uilabel(app.UIFigure);
            app.JOSTICKLabel.Text = 'JOSTICK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Appyen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end